classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image3            matlab.ui.control.Image
        Image2            matlab.ui.control.Image
        RECORDButton      matlab.ui.control.StateButton
        SimplePianoLabel  matlab.ui.control.Label
        LONGPRESSButton   matlab.ui.control.StateButton
        Button_13         matlab.ui.control.Button
        Button_12         matlab.ui.control.Button
        Button_11         matlab.ui.control.Button
        Button_10         matlab.ui.control.Button
        Button_9          matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        C6Button          matlab.ui.control.Button
        B5Button          matlab.ui.control.Button
        A5Button          matlab.ui.control.Button
        G5Button          matlab.ui.control.Button
        E5Button          matlab.ui.control.Button
        F5Button          matlab.ui.control.Button
        D5Button          matlab.ui.control.Button
        C5Button          matlab.ui.control.Button
        B4Button          matlab.ui.control.Button
        G4Button          matlab.ui.control.Button
        A4Button          matlab.ui.control.Button
        F4Button          matlab.ui.control.Button
        E4Button          matlab.ui.control.Button
        D4Button          matlab.ui.control.Button
        B3Button          matlab.ui.control.Button
        C4Button          matlab.ui.control.Button
        A3Button          matlab.ui.control.Button
        G3Button          matlab.ui.control.Button
        F3Button          matlab.ui.control.Button
        Image             matlab.ui.control.Image
    end

    
    properties (Access = private)
        samplefreq = 44100;
        time = 4; % Description
        rec = 0; % Description
        song = [] % Description
    end
    
    methods (Access = private)
        
        function wave = wavefunc(app,notenum,time)
                 t = 0:1/app.samplefreq:4/time;
                  %Formula to determine frequency of a note from its notenumber
                 freq = 440*2^((notenum-49)/12);
                  %Code to waves to simulate the sound of a piano
                 wave = sin(1* 2 * pi *freq * t) .* exp(-0.0004 * 2 * pi * freq * t) / 1;
                 wave = wave+sin(2* 2 * pi *freq * t) .* exp(-0.0004 * 2 * pi * freq * t) / 2;
                 wave = wave+sin(3* 2 * pi *freq * t) .* exp(-0.0004 * 2 * pi * freq * t) / 4;
                 wave = wave+sin(4* 2 * pi *freq * t) .* exp(-0.0004 * 2 * pi * freq * t) / 8;
                 wave = wave+sin(5* 2 * pi *freq * t) .* exp(-0.0004 * 2 * pi * freq * t) / 16;
                 wave = wave+sin(6* 2 * pi *freq * t) .* exp(-0.0004 * 2 * pi * freq * t) / 32;
                 wave= wave+(wave.*wave.*wave);
                 wave=wave/6;
                 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: F3Button
        function F3ButtonPushed(app, event)
         notenum=33;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end        
        end

        % Button pushed function: G3Button
        function G3ButtonPushed(app, event)
            notenum=35;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: A3Button
        function A3ButtonPushed(app, event)
            notenum=37;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: B3Button
        function B3ButtonPushed(app, event)
            notenum=39;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: C4Button
        function C4ButtonPushed(app, event)
            notenum=40;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: D4Button
        function D4ButtonPushed(app, event)
            notenum=42;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: E4Button
        function E4ButtonPushed(app, event)
            notenum=44;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: F4Button
        function F4ButtonPushed(app, event)
            notenum=45;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: G4Button
        function G4ButtonPushed(app, event)
            notenum=47;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: A4Button
        function A4ButtonPushed(app, event)
            notenum=49;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: B4Button
        function B4ButtonPushed(app, event)
            notenum=51;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: C5Button
        function C5ButtonPushed(app, event)
            notenum=52;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: D5Button
        function D5ButtonPushed(app, event)
            notenum=54;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: E5Button
        function E5ButtonPushed(app, event)
            notenum=56;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: F5Button
        function F5ButtonPushed(app, event)
            notenum=57;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: G5Button
        function G5ButtonPushed(app, event)
            notenum=59;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: A5Button
        function A5ButtonPushed(app, event)
            notenum=61;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: B5Button
        function B5ButtonPushed(app, event)
            notenum=63;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: C6Button
        function C6ButtonPushed(app, event)
            notenum=64;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Callback function
        function RECORDButtonPushed(app, event)
            
        end

        % Value changed function: LONGPRESSButton
        function LONGPRESSButtonValueChanged(app, event)
            value = app.LONGPRESSButton.Value;
            if(value==1)
                app.time=0.5;
            elseif(value==0)
                app.time=4;
            end
        end

        % Value changed function: RECORDButton
        function RECORDButtonValueChanged(app, event)
            app.rec = app.RECORDButton.Value;
            if(app.rec==0)
                audiowrite('Recording.wav',app.song,app.samplefreq,'BitsPerSample',32);
                msgbox('Recording Stopped');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
              notenum=34;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
              notenum=36;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
              notenum=38;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
              notenum=41;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
              notenum=43;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
              notenum=46;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
              notenum=48;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
              notenum=50;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
              notenum=53;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
              notenum=55;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
              notenum=58;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
              notenum=60;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
              notenum=62;                
         wave=app.wavefunc(notenum,app.time);                
         sound(wave,app.samplefreq,24);                
         if (app.rec==1)                    
            app.song=[app.song wave];                
         end     
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            app.time=4;
            
            
 % A3A3 B3A3 D4C4#           
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=39; % B3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=42; % D4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=41; % C4#
            wave=app.wavefunc(notenum,app.time/2);
            sound(wave,app.samplefreq,24);
            pause(1.4)
 % A3A3 B3A3 E4D4
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=39; % B3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=44; % E4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=42; % D4
            wave=app.wavefunc(notenum,app.time/2);
            sound(wave,app.samplefreq,24);
            pause(1.4)
 % A3A3 A4F4# D4 C4#B3           
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=49; % A4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=46; % F4#
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=42; % D4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=41; % C4#
            wave=app.wavefunc(notenum,app.time/2);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=39; % B3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(1.4)
 % G4G4 F4#D4 E4D4  
            notenum=47; % G4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=47; % G4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=46; % F4#
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=42; % D4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=44; % E4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.65)
            notenum=42; % D4
            wave=app.wavefunc(notenum,app.time/2);
            sound(wave,app.samplefreq,24);
            pause(1.4)
 % A3A3 B3A3 D4C4#           
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=39; % B3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=42; % D4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=41; % C4#
            wave=app.wavefunc(notenum,app.time/2);
            sound(wave,app.samplefreq,24);
            pause(1.4)
 % A3A3 B3A3 E4D4
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=39; % B3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=44; % E4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=42; % D4
            wave=app.wavefunc(notenum,app.time/2);
            sound(wave,app.samplefreq,24);
            pause(1.4)
  % A3A3 A4F4# D4 C4#B3           
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=37; % A3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=49; % A4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=46; % F4#
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=42; % D4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.90)
            notenum=41; % C4#
            wave=app.wavefunc(notenum,app.time/2);
            sound(wave,app.samplefreq,24);
            pause(.90)
            notenum=39; % B3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(1.6)
 % G4G4 F4#D4 E4D4  
            notenum=47; % G4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.90)
            notenum=47; % G4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.90)
            notenum=46; % F4#
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.90)
            notenum=42; % D4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.90)
            notenum=44; % E4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.90)
            notenum=42; % D4
            wave=app.wavefunc(notenum,app.time/2);
            sound(wave,app.samplefreq,24);
            pause(1.4)
 
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
             app.time=4;
            
 % B3 E4 G4 F4# E4 B4 A4 F4#
            notenum=39; % B3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.5)
            notenum=44; % E4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.8)
            notenum=47; % G4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.3)
            notenum=46; % F4#
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.3)
            notenum=44; % E4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.8)
            notenum=51; % B4
            wave=app.wavefunc(notenum,app.time/2);
            sound(wave,app.samplefreq,24);
            pause(.3)
            notenum=49; % A4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(1.0)
            notenum=46; % F4#
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(0.35)
            pause(0.5)
 % E4 G4 F4# D4# F4 B3
            notenum=44; % E4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.35)
            pause(0.35)
            notenum=47; % G4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.5)
            notenum=46; % F4#
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(0.3)
            notenum=43; % D4#
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=45; % F4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(0.5)
            notenum=39; % B3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(0.7)
            
 % B3 E4 G4 F4# E4 B4 D5 C5# C5
            notenum=39; % B3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(0.55)
            notenum=44; % E4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.80)
            notenum=47; % G4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.3)
            notenum=46; % F4#
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.30)
            notenum=44; % E4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(0.44)
            notenum=51; % B4
            wave=app.wavefunc(notenum,app.time/2);
            sound(wave,app.samplefreq,24);
            pause(.40)
            notenum=54; % D5
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.60)
            notenum=53; % C5#
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.30)
            notenum=52; % C5
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(1.0)  
            
 % G4# C5 A4# B3 G4 E4    
            notenum=48; % G4#
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.30)
            notenum=52; % C5
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.40)
            notenum=50; % A4#
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.50)
            notenum=39; % B3
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(.50)
            notenum=47; % G4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(0.8)
            notenum=44; % E4
            wave=app.wavefunc(notenum,app.time);
            sound(wave,app.samplefreq,24);
            pause(1.0)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '7.jpg';
            app.F3Button = uibutton(app.UIFigure, 'push');
            app.F3Button.ButtonPushedFcn = createCallbackFcn(app, @F3ButtonPushed, true);
            app.F3Button.Text = 'F3';
            app.G3Button = uibutton(app.UIFigure, 'push');
            app.G3Button.ButtonPushedFcn = createCallbackFcn(app, @G3ButtonPushed, true);
            app.G3Button.Text = 'G3';
            app.A3Button = uibutton(app.UIFigure, 'push');
            app.A3Button.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.A3Button.Text = 'A3';
            app.C4Button = uibutton(app.UIFigure, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.C4Button.Text = 'C4';
            app.B3Button = uibutton(app.UIFigure, 'push');
            app.B3Button.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3Button.Text = 'B3';
            app.D4Button = uibutton(app.UIFigure, 'push');
            app.D4Button.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.D4Button.Text = 'D4';
            app.E4Button = uibutton(app.UIFigure, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4ButtonPushed, true);
            app.E4Button.Text = 'E4';
            app.F4Button = uibutton(app.UIFigure, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4Button.Text = 'F4';
            app.A4Button = uibutton(app.UIFigure, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.A4Button.Text = 'A4';
            app.G4Button = uibutton(app.UIFigure, 'push');
            app.G4Button.ButtonPushedFcn = createCallbackFcn(app, @G4ButtonPushed, true);
            app.G4Button.Text = 'G4';
            app.B4Button = uibutton(app.UIFigure, 'push');
            app.B4Button.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4Button.Text = 'B4';
            app.C5Button = uibutton(app.UIFigure, 'push');
            app.C5Button.ButtonPushedFcn = createCallbackFcn(app, @C5ButtonPushed, true);
            app.C5Button.Text = 'C5';
            app.D5Button = uibutton(app.UIFigure, 'push');
            app.D5Button.ButtonPushedFcn = createCallbackFcn(app, @D5ButtonPushed, true);
            app.D5Button.Text = 'D5';
            app.F5Button = uibutton(app.UIFigure, 'push');
            app.F5Button.ButtonPushedFcn = createCallbackFcn(app, @F5ButtonPushed, true);
            app.F5Button.Text = 'F5';
            app.E5Button = uibutton(app.UIFigure, 'push');
            app.E5Button.ButtonPushedFcn = createCallbackFcn(app, @E5ButtonPushed, true);
            app.E5Button.Text = 'E5';
            app.G5Button = uibutton(app.UIFigure, 'push');
            app.G5Button.ButtonPushedFcn = createCallbackFcn(app, @G5ButtonPushed, true);
            app.G5Button.Text = 'G5';
            app.A5Button = uibutton(app.UIFigure, 'push');
            app.A5Button.ButtonPushedFcn = createCallbackFcn(app, @A5ButtonPushed, true);
            app.A5Button.Text = 'A5';
            app.B5Button = uibutton(app.UIFigure, 'push');
            app.B5Button.ButtonPushedFcn = createCallbackFcn(app, @B5ButtonPushed, true);
            app.B5Button.Text = 'B5';
            app.C6Button = uibutton(app.UIFigure, 'push');
            app.C6Button.ButtonPushedFcn = createCallbackFcn(app, @C6ButtonPushed, true);
            app.C6Button.Text = 'C6';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '';
            app.LONGPRESSButton = uibutton(app.UIFigure, 'state');
            app.LONGPRESSButton.ValueChangedFcn = createCallbackFcn(app, @LONGPRESSButtonValueChanged, true);
            app.LONGPRESSButton.Text = 'LONG PRESS';
            app.SimplePianoLabel = uilabel(app.UIFigure);
            app.SimplePianoLabel.Text = 'Simple Piano';
            app.RECORDButton = uibutton(app.UIFigure, 'state');
            app.RECORDButton.ValueChangedFcn = createCallbackFcn(app, @RECORDButtonValueChanged, true);
            app.RECORDButton.Text = 'RECORD';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = '16.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = '12.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end